classdef AtlasCIT168 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        AtlasPanel                matlab.ui.container.Panel
        CheckBox_STN1             matlab.ui.control.CheckBox
        CheckBox_STN2             matlab.ui.control.CheckBox
        CheckBox_STN2motor        matlab.ui.control.CheckBox
        CheckBox_STN2associative  matlab.ui.control.CheckBox
        CheckBox_STN2limbic       matlab.ui.control.CheckBox
        CheckBox_RN2              matlab.ui.control.CheckBox
        CheckBox_VIM              matlab.ui.control.CheckBox
        CheckBox_VA               matlab.ui.control.CheckBox
        CheckBox_VOICM            matlab.ui.control.CheckBox
        CheckBox_Ca               matlab.ui.control.CheckBox
        CheckBox_Th               matlab.ui.control.CheckBox
        CheckBox_Pu               matlab.ui.control.CheckBox
        CheckBox_NAC              matlab.ui.control.CheckBox
        CheckBox_GPi1             matlab.ui.control.CheckBox
        CheckBox_GPi2             matlab.ui.control.CheckBox
        CheckBox_GPisensorimotor  matlab.ui.control.CheckBox
        CheckBox_GPisensory       matlab.ui.control.CheckBox
        CheckBox_GPiprimarymotor  matlab.ui.control.CheckBox
        CheckBox_GPipremotor      matlab.ui.control.CheckBox
        CheckBox_GPipostparietal  matlab.ui.control.CheckBox
        CheckBox_EXA              matlab.ui.control.CheckBox
        CheckBox_HN               matlab.ui.control.CheckBox
        CheckBox_HTH              matlab.ui.control.CheckBox
        CheckBox_SNr              matlab.ui.control.CheckBox
        CheckBox_GPe1             matlab.ui.control.CheckBox
        CheckBox_GPe2             matlab.ui.control.CheckBox
        CheckBox_SNc              matlab.ui.control.CheckBox
        CheckBox_RN1              matlab.ui.control.CheckBox
        CheckBox_GPioccipital     matlab.ui.control.CheckBox
        CheckBox_GPitemporal      matlab.ui.control.CheckBox
        CheckBox_GPiprefrontal    matlab.ui.control.CheckBox
        CheckBox_VTA              matlab.ui.control.CheckBox
        CheckBox_VPe              matlab.ui.control.CheckBox
        CheckBox_MN               matlab.ui.control.CheckBox
        CheckBox_PBP              matlab.ui.control.CheckBox
        CheckBox_ALL              matlab.ui.control.CheckBox
    end

    
    properties (Access = public)
        struc_brain = zeros(35,1);
        struc_brain_name = [];
        refgui = [];
        atlas = [];
        fastfield_folder = [];

       % Description
%       % uiFigure = FastField
%        alt=[];
%       UIFigure = [];
      
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, refgui1)
            app.refgui = refgui1;

        end

        % Value changed function: CheckBox_STN1
        function CheckBox_STN1ValueChanged(app, event)
            tar = app.CheckBox_STN1.Text;
            
            atlases = app.refgui.atl;
            [Ind,] = find(strcmp(tar,atlases.labels{1, 1})==1);
            if app.CheckBox_STN1.Value
                app.refgui.show_atlas(tar);
                app.refgui.struc_brain1(Ind) = 1;
            else
                delete(app.refgui.hTarget(Ind));
                app.refgui.struc_brain1(Ind) = 0;
            end
        end

        % Value changed function: CheckBox_EXA
        function CheckBox_EXAValueChanged(app, event)
            tar = app.CheckBox_EXA.Text;
            
            atlases = app.refgui.atl;
            [Ind,] = find(strcmp(tar,atlases.labels{1, 1})==1);
            if app.CheckBox_EXA.Value
                app.refgui.show_atlas(tar);
                app.refgui.struc_brain1(Ind) = 1;
            else
                delete(app.refgui.hTarget(Ind));
                app.refgui.struc_brain1(Ind) = 0;
            end
        end

        % Value changed function: CheckBox_STN2
        function CheckBox_STN2ValueChanged(app, event)
             tar = app.CheckBox_STN2.Text;
            
            atlases = app.refgui.atl;
            [Ind,] = find(strcmp(tar,atlases.labels{1, 1})==1);
            if app.CheckBox_STN2.Value
                app.refgui.show_atlas(tar);
                app.refgui.struc_brain1(Ind) = 1;
            else
                delete(app.refgui.hTarget(Ind));
                app.refgui.struc_brain1(Ind) = 0;
            end
        end

        % Value changed function: CheckBox_STN2motor
        function CheckBox_STN2motorValueChanged(app, event)
            tar = app.CheckBox_STN2motor.Text;
            
            atlases = app.refgui.atl;
            [Ind,] = find(strcmp(tar,atlases.labels{1, 1})==1);
            if app.CheckBox_STN2motor.Value
                app.refgui.show_atlas(tar);
                app.refgui.struc_brain1(Ind) = 1;
            else
                delete(app.refgui.hTarget(Ind));
                app.refgui.struc_brain1(Ind) = 0;
            end
        end

        % Value changed function: CheckBox_STN2associative
        function CheckBox_STN2associativeValueChanged(app, event)
            tar = app.CheckBox_STN2associative.Text;
            
            atlases = app.refgui.atl;
            [Ind,] = find(strcmp(tar,atlases.labels{1, 1})==1);
            if app.CheckBox_STN2associative.Value
                app.refgui.show_atlas(tar);
                app.refgui.struc_brain1(Ind) = 1;
            else
                delete(app.refgui.hTarget(Ind));
                app.refgui.struc_brain1(Ind) = 0;
            end
        end

        % Value changed function: CheckBox_STN2limbic
        function CheckBox_STN2limbicValueChanged(app, event)
            tar = app.CheckBox_STN2limbic.Text;
            
            atlases = app.refgui.atl;
            [Ind,] = find(strcmp(tar,atlases.labels{1, 1})==1);
            if app.CheckBox_STN2limbic.Value
                app.refgui.show_atlas(tar);
                app.refgui.struc_brain1(Ind) = 1;
            else
                delete(app.refgui.hTarget(Ind));
                app.refgui.struc_brain1(Ind) = 0;
            end
            
        end

        % Value changed function: CheckBox_RN1
        function CheckBox_RN1ValueChanged(app, event)
            tar = app.CheckBox_RN1.Text;
            
            atlases = app.refgui.atl;
            [Ind,] = find(strcmp(tar,atlases.labels{1, 1})==1);
            if app.CheckBox_RN1.Value
                app.refgui.show_atlas(tar);
                app.refgui.struc_brain1(Ind) = 1;
            else
                delete(app.refgui.hTarget(Ind));
                app.refgui.struc_brain1(Ind) = 0;
            end
        end

        % Value changed function: CheckBox_RN2
        function CheckBox_RN2ValueChanged(app, event)
            tar = app.CheckBox_RN2.Text;
            
            atlases = app.refgui.atl;
            [Ind,] = find(strcmp(tar,atlases.labels{1, 1})==1);
            if app.CheckBox_RN2.Value
                app.refgui.show_atlas(tar);
                app.refgui.struc_brain1(Ind) = 1;
            else
                delete(app.refgui.hTarget(Ind));
                app.refgui.struc_brain1(Ind) = 0;
            end
        end

        % Value changed function: CheckBox_MN
        function CheckBox_MNValueChanged(app, event)
            tar = app.CheckBox_MN.Text;
            
            atlases = app.refgui.atl;
            [Ind,] = find(strcmp(tar,atlases.labels{1, 1})==1);
            if app.CheckBox_MN.Value
                app.refgui.show_atlas(tar);
                app.refgui.struc_brain1(Ind) = 1;
            else
                delete(app.refgui.hTarget(Ind));
                app.refgui.struc_brain1(Ind) = 0;
            end
            
        end

        % Value changed function: CheckBox_PBP
        function CheckBox_PBPValueChanged(app, event)
            tar = app.CheckBox_PBP.Text;
            
            atlases = app.refgui.atl;
            [Ind,] = find(strcmp(tar,atlases.labels{1, 1})==1);
            if app.CheckBox_PBP.Value
                app.refgui.show_atlas(tar);
                app.refgui.struc_brain1(Ind) = 1;
            else
                delete(app.refgui.hTarget(Ind));
                app.refgui.struc_brain1(Ind) = 0;
            end
        end

        % Value changed function: CheckBox_VIM
        function CheckBox_VIMValueChanged(app, event)
            tar = app.CheckBox_VIM.Text;
            
            atlases = app.refgui.atl;
            [Ind,] = find(strcmp(tar,atlases.labels{1, 1})==1);
            if app.CheckBox_VIM.Value
                app.refgui.show_atlas(tar);
                app.refgui.struc_brain1(Ind) = 1;
            else
                delete(app.refgui.hTarget(Ind));
                app.refgui.struc_brain1(Ind) = 0;
            end
        end

        % Value changed function: CheckBox_VA
        function CheckBox_VAValueChanged(app, event)
            tar = app.CheckBox_VA.Text;
            
            atlases = app.refgui.atl;
            [Ind,] = find(strcmp(tar,atlases.labels{1, 1})==1);
            if app.CheckBox_VA.Value
                app.refgui.show_atlas(tar);
                app.refgui.struc_brain1(Ind) = 1;
            else
                delete(app.refgui.hTarget(Ind));
                app.refgui.struc_brain1(Ind) = 0;
            end
        end

        % Value changed function: CheckBox_VOICM
        function CheckBox_VOICMValueChanged(app, event)
            tar = app.CheckBox_VOICM.Text;
            
            atlases = app.refgui.atl;
            [Ind,] = find(strcmp(tar,atlases.labels{1, 1})==1);
            if app.CheckBox_VOICM.Value
                app.refgui.show_atlas(tar);
                app.refgui.struc_brain1(Ind) = 1;
            else
                delete(app.refgui.hTarget(Ind));
                app.refgui.struc_brain1(Ind) = 0;
            end
        end

        % Value changed function: CheckBox_Th
        function CheckBox_ThValueChanged(app, event)
            tar = app.CheckBox_Th.Text;
            
            atlases = app.refgui.atl;
            [Ind,] = find(strcmp(tar,atlases.labels{1, 1})==1);
            if app.CheckBox_Th.Value
                app.refgui.show_atlas(tar);
                app.refgui.struc_brain1(Ind) = 1;
            else
                delete(app.refgui.hTarget(Ind));
                app.refgui.struc_brain1(Ind) = 0;
            end
            
        end

        % Value changed function: CheckBox_Pu
        function CheckBox_PuValueChanged(app, event)
            tar = app.CheckBox_Pu.Text;
            
            atlases = app.refgui.atl;
            [Ind,] = find(strcmp(tar,atlases.labels{1, 1})==1);
            if app.CheckBox_Pu.Value
                app.refgui.show_atlas(tar);
                app.refgui.struc_brain1(Ind) = 1;
            else
                delete(app.refgui.hTarget(Ind));
                app.refgui.struc_brain1(Ind) = 0;
            end
        end

        % Value changed function: CheckBox_Ca
        function CheckBox_CaValueChanged(app, event)
            tar = app.CheckBox_Ca.Text;
            
            atlases = app.refgui.atl;
            [Ind,] = find(strcmp(tar,atlases.labels{1, 1})==1);
            if app.CheckBox_Ca.Value
                app.refgui.show_atlas(tar);
                app.refgui.struc_brain1(Ind) = 1;
            else
                delete(app.refgui.hTarget(Ind));
                app.refgui.struc_brain1(Ind) = 0;
            end
        end

        % Value changed function: CheckBox_NAC
        function CheckBox_NACValueChanged(app, event)
            tar = app.CheckBox_NAC.Text;
            
            atlases = app.refgui.atl;
            [Ind,] = find(strcmp(tar,atlases.labels{1, 1})==1);
            if app.CheckBox_NAC.Value
                app.refgui.show_atlas(tar);
                app.refgui.struc_brain1(Ind) = 1;
            else
                delete(app.refgui.hTarget(Ind));
                app.refgui.struc_brain1(Ind) = 0;
            end
        end

        % Value changed function: CheckBox_VTA
        function CheckBox_VTAValueChanged(app, event)
            tar = app.CheckBox_VTA.Text;
            
            atlases = app.refgui.atl;
            [Ind,] = find(strcmp(tar,atlases.labels{1, 1})==1);
            if app.CheckBox_VTA.Value
                app.refgui.show_atlas(tar);
                app.refgui.struc_brain1(Ind) = 1;
            else
                delete(app.refgui.hTarget(Ind));
                app.refgui.struc_brain1(Ind) = 0;
            end
            
        end

        % Value changed function: CheckBox_VPe
        function CheckBox_VPeValueChanged(app, event)
            tar = app.CheckBox_VPe.Text;
            
            atlases = app.refgui.atl;
            [Ind,] = find(strcmp(tar,atlases.labels{1, 1})==1);
            if app.CheckBox_VPe.Value
                app.refgui.show_atlas(tar);
                app.refgui.struc_brain1(Ind) = 1;
            else
                delete(app.refgui.hTarget(Ind));
                app.refgui.struc_brain1(Ind) = 0;
            end
        end

        % Value changed function: CheckBox_GPi1
        function CheckBox_GPi1ValueChanged(app, event)
            tar = app.CheckBox_GPi1.Text;
            
            atlases = app.refgui.atl;
            [Ind,] = find(strcmp(tar,atlases.labels{1, 1})==1);
            if app.CheckBox_GPi1.Value
                app.refgui.show_atlas(tar);
                app.refgui.struc_brain1(Ind) = 1;
            else
                delete(app.refgui.hTarget(Ind));
                app.refgui.struc_brain1(Ind) = 0;
            end
        end

        % Value changed function: CheckBox_GPi2
        function CheckBox_GPi2ValueChanged(app, event)
            tar = app.CheckBox_GPi2.Text;
            
            atlases = app.refgui.atl;
            [Ind,] = find(strcmp(tar,atlases.labels{1, 1})==1);
            if app.CheckBox_GPi2.Value
                app.refgui.show_atlas(tar);
                app.refgui.struc_brain1(Ind) = 1;
            else
                delete(app.refgui.hTarget(Ind));
                app.refgui.struc_brain1(Ind) = 0;
            end
        end

        % Value changed function: CheckBox_GPisensorimotor
        function CheckBox_GPisensorimotorValueChanged(app, event)
            tar = app.CheckBox_GPisensorimotor.Text;
            
            atlases = app.refgui.atl;
            [Ind,] = find(strcmp(tar,atlases.labels{1, 1})==1);
            if app.CheckBox_GPisensorimotor.Value
                app.refgui.show_atlas(tar);
                app.refgui.struc_brain1(Ind) = 1;
            else
                delete(app.refgui.hTarget(Ind));
                app.refgui.struc_brain1(Ind) = 0;
            end
        end

        % Value changed function: CheckBox_GPiprimarymotor
        function CheckBox_GPiprimarymotorValueChanged(app, event)
              tar = app.CheckBox_GPiprimarymotor.Text;
            
            atlases = app.refgui.atl;
            [Ind,] = find(strcmp(tar,atlases.labels{1, 1})==1);
            if app.CheckBox_GPiprimarymotor.Value
                app.refgui.show_atlas(tar);
                app.refgui.struc_brain1(Ind) = 1;
            else
                delete(app.refgui.hTarget(Ind));
                app.refgui.struc_brain1(Ind) = 0;
            end
        end

        % Value changed function: CheckBox_GPipremotor
        function CheckBox_GPipremotorValueChanged(app, event)
              tar = app.CheckBox_GPipremotor.Text;
            
            atlases = app.refgui.atl;
            [Ind,] = find(strcmp(tar,atlases.labels{1, 1})==1);
            if app.CheckBox_GPipremotor.Value
                app.refgui.show_atlas(tar);
                app.refgui.struc_brain1(Ind) = 1;
            else
                delete(app.refgui.hTarget(Ind));
                app.refgui.struc_brain1(Ind) = 0;
            end
            
        end

        % Value changed function: CheckBox_GPisensory
        function CheckBox_GPisensoryValueChanged(app, event)
            tar = app.CheckBox_GPisensory.Text;
            
            atlases = app.refgui.atl;
            [Ind,] = find(strcmp(tar,atlases.labels{1, 1})==1);
            if app.CheckBox_GPisensory.Value
                app.refgui.show_atlas(tar);
                app.refgui.struc_brain1(Ind) = 1;
            else
                delete(app.refgui.hTarget(Ind));
                app.refgui.struc_brain1(Ind) = 0;
            end
            
        end

        % Value changed function: CheckBox_GPipostparietal
        function CheckBox_GPipostparietalValueChanged(app, event)
            tar = app.CheckBox_GPipostparietal.Text;
            
            atlases = app.refgui.atl;
            [Ind,] = find(strcmp(tar,atlases.labels{1, 1})==1);
            if app.CheckBox_GPipostparietal.Value
                app.refgui.show_atlas(tar);
                app.refgui.struc_brain1(Ind) = 1;
            else
                delete(app.refgui.hTarget(Ind));
                app.refgui.struc_brain1(Ind) = 0;
            end
            
        end

        % Value changed function: CheckBox_GPioccipital
        function CheckBox_GPioccipitalValueChanged(app, event)
            tar = app.CheckBox_GPioccipital.Text;
            
            atlases = app.refgui.atl;
            [Ind,] = find(strcmp(tar,atlases.labels{1, 1})==1);
            if app.CheckBox_GPioccipital.Value
                app.refgui.show_atlas(tar);
                app.refgui.struc_brain1(Ind) = 1;
            else
                delete(app.refgui.hTarget(Ind));
                app.refgui.struc_brain1(Ind) = 0;
            end
            
        end

        % Value changed function: CheckBox_GPitemporal
        function CheckBox_GPitemporalValueChanged(app, event)
            tar = app.CheckBox_GPitemporal.Text;
            
            atlases = app.refgui.atl;
            [Ind,] = find(strcmp(tar,atlases.labels{1, 1})==1);
            if app.CheckBox_GPitemporal.Value
                app.refgui.show_atlas(tar);
                app.refgui.struc_brain1(Ind) = 1;
            else
                delete(app.refgui.hTarget(Ind));
                app.refgui.struc_brain1(Ind) = 0;
            end
            
        end

        % Value changed function: CheckBox_HN
        function CheckBox_HNValueChanged(app, event)
              tar = app.CheckBox_HN.Text;
            
            atlases = app.refgui.atl;
            [Ind,] = find(strcmp(tar,atlases.labels{1, 1})==1);
            if app.CheckBox_HN.Value
                app.refgui.show_atlas(tar);
                app.refgui.struc_brain1(Ind) = 1;
            else
                delete(app.refgui.hTarget(Ind));
                app.refgui.struc_brain1(Ind) = 0;
            end
            
        end

        % Value changed function: CheckBox_HTH
        function CheckBox_HTHValueChanged(app, event)
             tar = app.CheckBox_HTH.Text;
            
            atlases = app.refgui.atl;
            [Ind,] = find(strcmp(tar,atlases.labels{1, 1})==1);
            if app.CheckBox_HTH.Value
                app.refgui.show_atlas(tar);
                app.refgui.struc_brain1(Ind) = 1;
            else
                delete(app.refgui.hTarget(Ind));
                app.refgui.struc_brain1(Ind) = 0;
            end
            
        end

        % Value changed function: CheckBox_GPe1
        function CheckBox_GPe1ValueChanged(app, event)
            tar = app.CheckBox_GPe1.Text;
            
            atlases = app.refgui.atl;
            [Ind,] = find(strcmp(tar,atlases.labels{1, 1})==1);
            if app.CheckBox_GPe1.Value
                app.refgui.show_atlas(tar);
                app.refgui.struc_brain1(Ind) = 1;
            else
                delete(app.refgui.hTarget(Ind));
                app.refgui.struc_brain1(Ind) = 0;
            end
        end

        % Value changed function: CheckBox_GPe2
        function CheckBox_GPe2ValueChanged(app, event)
            tar = app.CheckBox_GPe2.Text;
            
            atlases = app.refgui.atl;
            [Ind,] = find(strcmp(tar,atlases.labels{1, 1})==1);
            if app.CheckBox_GPe2.Value
                app.refgui.show_atlas(tar);
                app.refgui.struc_brain1(Ind) = 1;
            else
                delete(app.refgui.hTarget(Ind));
                app.refgui.struc_brain1(Ind) = 0;
            end
        end

        % Value changed function: CheckBox_SNr
        function CheckBox_SNrValueChanged(app, event)
            tar = app.CheckBox_SNr.Text;
            
            atlases = app.refgui.atl;
            [Ind,] = find(strcmp(tar,atlases.labels{1, 1})==1);
            if app.CheckBox_SNr.Value
                app.refgui.show_atlas(tar);
                app.refgui.struc_brain1(Ind) = 1;
            else
                delete(app.refgui.hTarget(Ind));
                app.refgui.struc_brain1(Ind) = 0;
            end
            
        end

        % Value changed function: CheckBox_SNc
        function CheckBox_SNcValueChanged(app, event)
            tar = app.CheckBox_SNc.Text;
            
            atlases = app.refgui.atl;
            [Ind,] = find(strcmp(tar,atlases.labels{1, 1})==1);
            if app.CheckBox_SNc.Value
                app.refgui.show_atlas(tar);
                app.refgui.struc_brain1(Ind) = 1;
            else
                delete(app.refgui.hTarget(Ind));
                app.refgui.struc_brain1(Ind) = 0;
            end
        end

        % Value changed function: CheckBox_GPiprefrontal
        function CheckBox_GPiprefrontalValueChanged(app, event)
            tar = app.CheckBox_GPiprefrontal.Text;
            
            atlases = app.refgui.atl;
            [Ind,] = find(strcmp(tar,atlases.labels{1, 1})==1);
            if app.CheckBox_GPiprefrontal.Value
                app.refgui.show_atlas(tar);
                app.refgui.struc_brain1(Ind) = 1;
            else
                delete(app.refgui.hTarget(Ind));
                app.refgui.struc_brain1(Ind) = 0;
            end
            
        end

        % Value changed function: CheckBox_ALL
        function CheckBox_ALLValueChanged(app, event)
            if app.CheckBox_ALL.Value
                app.refgui.struc_brain1 = ones(36,1);
                
                app.struc_brain_name{35} = app.CheckBox_VOICM.Text;
                app.struc_brain_name{34} = app.CheckBox_VIM.Text;
                app.struc_brain_name{33} = app.CheckBox_VA.Text;
                app.struc_brain_name{32} = app.CheckBox_Th.Text;
                app.struc_brain_name{31} = app.CheckBox_GPe2.Text;
                app.struc_brain_name{30} = app.CheckBox_RN2.Text;
                app.struc_brain_name{29} = app.CheckBox_GPiprefrontal.Text;
                app.struc_brain_name{28} = app.CheckBox_GPitemporal.Text;
                app.struc_brain_name{27} = app.CheckBox_GPioccipital.Text;
                app.struc_brain_name{26} = app.CheckBox_GPipostparietal.Text;
                app.struc_brain_name{25} = app.CheckBox_GPisensory.Text;
                app.struc_brain_name{24} = app.CheckBox_GPipremotor.Text;
                app.struc_brain_name{23} = app.CheckBox_GPiprimarymotor.Text;
                app.struc_brain_name{22} = app.CheckBox_GPisensorimotor.Text;
                app.struc_brain_name{21} = app.CheckBox_GPi2.Text;
                app.struc_brain_name{20} = app.CheckBox_STN2limbic.Text;
                app.struc_brain_name{19} = app.CheckBox_STN2associative.Text;
                app.struc_brain_name{18} = app.CheckBox_STN2motor.Text;
                app.struc_brain_name{17} = app.CheckBox_STN2.Text;
                app.struc_brain_name{16} = app.CheckBox_VPe.Text;
                app.struc_brain_name{15} = app.CheckBox_VTA.Text;
                app.struc_brain_name{14} = app.CheckBox_STN1.Text;
                app.struc_brain_name{13} = app.CheckBox_SNr.Text;
                app.struc_brain_name{12} = app.CheckBox_SNc.Text;
                app.struc_brain_name{11} = app.CheckBox_RN1.Text;
                app.struc_brain_name{10} = app.CheckBox_Pu.Text;
                app.struc_brain_name{9} = app.CheckBox_PBP.Text;
                app.struc_brain_name{8} = app.CheckBox_NAC.Text;
                app.struc_brain_name{7} = app.CheckBox_MN.Text;
                app.struc_brain_name{6} = app.CheckBox_HTH.Text;
                app.struc_brain_name{5} = app.CheckBox_HN.Text;
                app.struc_brain_name{4} = app.CheckBox_GPi1.Text;
                app.struc_brain_name{3} = app.CheckBox_GPe1.Text;
                app.struc_brain_name{2} = app.CheckBox_EXA.Text;
                app.struc_brain_name{1} = app.CheckBox_Ca.Text;
                
                app.CheckBox_Ca.Value = 1;
                app.CheckBox_EXA.Value = 1;
                app.CheckBox_GPe1.Value = 1;
                app.CheckBox_GPi1.Value = 1;
                app.CheckBox_HN.Value = 1;
                app.CheckBox_HTH.Value = 1;
                app.CheckBox_MN.Value = 1;
                app.CheckBox_NAC.Value = 1;
                app.CheckBox_PBP.Value = 1;
                app.CheckBox_Pu.Value = 1;
                app.CheckBox_RN1.Value = 1;
                app.CheckBox_SNc.Value = 1;
                app.CheckBox_SNr.Value = 1;
                app.CheckBox_STN1.Value = 1;
                app.CheckBox_VTA.Value = 1;
                app.CheckBox_VPe.Value = 1;
                app.CheckBox_STN2.Value = 1;
                app.CheckBox_STN2motor.Value = 1;
                app.CheckBox_STN2associative.Value = 1;
                app.CheckBox_STN2limbic.Value = 1;
                app.CheckBox_GPi2.Value = 1;
                app.CheckBox_GPisensorimotor.Value = 1;
                app.CheckBox_GPiprimarymotor.Value = 1;
                app.CheckBox_GPipremotor.Value = 1;
                app.CheckBox_GPisensory.Value = 1;
                app.CheckBox_GPipostparietal.Value = 1;
                app.CheckBox_GPioccipital.Value = 1;
                app.CheckBox_GPitemporal.Value = 1;
                app.CheckBox_GPiprefrontal.Value = 1;
                app.CheckBox_RN2.Value = 1;
                app.CheckBox_GPe2.Value = 1;
                app.CheckBox_Th.Value = 1;
                app.CheckBox_VA.Value = 1;
                app.CheckBox_VIM.Value = 1;
                app.CheckBox_VOICM.Value = 1;
                app.CheckBox_ALL.Value = 1;
                
                for kk = 1:length(app.struc_brain_name)
                    if isnan(app.refgui.hTarget(kk))
                        tar = app.struc_brain_name{kk};
                        app.refgui.show_atlas(tar);
                    end
                end
                
            else
                app.refgui.struc_brain1 = zeros(36,1);
                app.CheckBox_Ca.Value = 0;
                app.CheckBox_EXA.Value = 0;
                app.CheckBox_GPe1.Value = 0;
                app.CheckBox_GPi1.Value = 0;
                app.CheckBox_HN.Value = 0;
                app.CheckBox_HTH.Value = 0;
                app.CheckBox_MN.Value = 0;
                app.CheckBox_NAC.Value = 0;
                app.CheckBox_PBP.Value = 0;
                app.CheckBox_Pu.Value = 0;
                app.CheckBox_RN1.Value = 0;
                app.CheckBox_SNc.Value = 0;
                app.CheckBox_SNr.Value = 0;
                app.CheckBox_STN1.Value = 0;
                app.CheckBox_VTA.Value = 0;
                app.CheckBox_VPe.Value = 0;
                app.CheckBox_STN2.Value = 0;
                app.CheckBox_STN2motor.Value = 0;
                app.CheckBox_STN2associative.Value = 0;
                app.CheckBox_STN2limbic.Value = 0;
                app.CheckBox_GPi2.Value = 0;
                app.CheckBox_GPisensorimotor.Value = 0;
                app.CheckBox_GPiprimarymotor.Value = 0;
                app.CheckBox_GPipremotor.Value = 0;
                app.CheckBox_GPisensory.Value = 0;
                app.CheckBox_GPipostparietal.Value = 0;
                app.CheckBox_GPioccipital.Value = 0;
                app.CheckBox_GPitemporal.Value = 0;
                app.CheckBox_GPiprefrontal.Value = 0;
                app.CheckBox_RN2.Value = 0;
                app.CheckBox_GPe2.Value = 0;
                app.CheckBox_Th.Value = 0;
                app.CheckBox_VA.Value = 0;
                app.CheckBox_VIM.Value = 0;
                app.CheckBox_VOICM.Value = 0;
                app.CheckBox_ALL.Value = 0;
                
                 for kk = 1:length(app.struc_brain_name)
                   % tar = app.struc_brain_name{kk};
                    delete(app.refgui.hTarget(kk));
                    app.refgui.hTarget(kk) = NaN;
                    
                 end
                 
            end
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.AtlasPanel = uipanel(app.UIFigure);
            app.AtlasPanel.Title = 'Atlas';
            app.CheckBox_STN1 = uicheckbox(app.AtlasPanel);
            app.CheckBox_STN1.ValueChangedFcn = createCallbackFcn(app, @CheckBox_STN1ValueChanged, true);
            app.CheckBox_STN1.Text = 'STN1';
            app.CheckBox_STN2 = uicheckbox(app.AtlasPanel);
            app.CheckBox_STN2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_STN2ValueChanged, true);
            app.CheckBox_STN2.Text = 'STN2';
            app.CheckBox_STN2motor = uicheckbox(app.AtlasPanel);
            app.CheckBox_STN2motor.ValueChangedFcn = createCallbackFcn(app, @CheckBox_STN2motorValueChanged, true);
            app.CheckBox_STN2motor.Text = 'STN2 motor';
            app.CheckBox_STN2associative = uicheckbox(app.AtlasPanel);
            app.CheckBox_STN2associative.ValueChangedFcn = createCallbackFcn(app, @CheckBox_STN2associativeValueChanged, true);
            app.CheckBox_STN2associative.Text = 'STN2 associative';
            app.CheckBox_STN2limbic = uicheckbox(app.AtlasPanel);
            app.CheckBox_STN2limbic.ValueChangedFcn = createCallbackFcn(app, @CheckBox_STN2limbicValueChanged, true);
            app.CheckBox_STN2limbic.Text = 'STN2 limbic';
            app.CheckBox_RN2 = uicheckbox(app.AtlasPanel);
            app.CheckBox_RN2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_RN2ValueChanged, true);
            app.CheckBox_RN2.Text = 'RN2';
            app.CheckBox_VIM = uicheckbox(app.AtlasPanel);
            app.CheckBox_VIM.ValueChangedFcn = createCallbackFcn(app, @CheckBox_VIMValueChanged, true);
            app.CheckBox_VIM.Text = 'VIM';
            app.CheckBox_VA = uicheckbox(app.AtlasPanel);
            app.CheckBox_VA.ValueChangedFcn = createCallbackFcn(app, @CheckBox_VAValueChanged, true);
            app.CheckBox_VA.Text = 'VA';
            app.CheckBox_VOICM = uicheckbox(app.AtlasPanel);
            app.CheckBox_VOICM.ValueChangedFcn = createCallbackFcn(app, @CheckBox_VOICMValueChanged, true);
            app.CheckBox_VOICM.Text = 'VOI/CM';
            app.CheckBox_Ca = uicheckbox(app.AtlasPanel);
            app.CheckBox_Ca.ValueChangedFcn = createCallbackFcn(app, @CheckBox_CaValueChanged, true);
            app.CheckBox_Ca.Text = 'Ca';
            app.CheckBox_Th = uicheckbox(app.AtlasPanel);
            app.CheckBox_Th.ValueChangedFcn = createCallbackFcn(app, @CheckBox_ThValueChanged, true);
            app.CheckBox_Th.Text = 'Th';
            app.CheckBox_Pu = uicheckbox(app.AtlasPanel);
            app.CheckBox_Pu.ValueChangedFcn = createCallbackFcn(app, @CheckBox_PuValueChanged, true);
            app.CheckBox_Pu.Text = 'Pu';
            app.CheckBox_NAC = uicheckbox(app.AtlasPanel);
            app.CheckBox_NAC.ValueChangedFcn = createCallbackFcn(app, @CheckBox_NACValueChanged, true);
            app.CheckBox_NAC.Text = 'NAC';
            app.CheckBox_GPi1 = uicheckbox(app.AtlasPanel);
            app.CheckBox_GPi1.ValueChangedFcn = createCallbackFcn(app, @CheckBox_GPi1ValueChanged, true);
            app.CheckBox_GPi1.Text = 'GPi1';
            app.CheckBox_GPi2 = uicheckbox(app.AtlasPanel);
            app.CheckBox_GPi2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_GPi2ValueChanged, true);
            app.CheckBox_GPi2.Text = 'GPi2';
            app.CheckBox_GPisensorimotor = uicheckbox(app.AtlasPanel);
            app.CheckBox_GPisensorimotor.ValueChangedFcn = createCallbackFcn(app, @CheckBox_GPisensorimotorValueChanged, true);
            app.CheckBox_GPisensorimotor.Text = 'GPi sensorimotor';
            app.CheckBox_GPisensory = uicheckbox(app.AtlasPanel);
            app.CheckBox_GPisensory.ValueChangedFcn = createCallbackFcn(app, @CheckBox_GPisensoryValueChanged, true);
            app.CheckBox_GPisensory.Text = 'GPi sensory';
            app.CheckBox_GPiprimarymotor = uicheckbox(app.AtlasPanel);
            app.CheckBox_GPiprimarymotor.ValueChangedFcn = createCallbackFcn(app, @CheckBox_GPiprimarymotorValueChanged, true);
            app.CheckBox_GPiprimarymotor.Text = 'GPi primarymotor';
            app.CheckBox_GPipremotor = uicheckbox(app.AtlasPanel);
            app.CheckBox_GPipremotor.ValueChangedFcn = createCallbackFcn(app, @CheckBox_GPipremotorValueChanged, true);
            app.CheckBox_GPipremotor.Text = 'GPi premotor';
            app.CheckBox_GPipostparietal = uicheckbox(app.AtlasPanel);
            app.CheckBox_GPipostparietal.ValueChangedFcn = createCallbackFcn(app, @CheckBox_GPipostparietalValueChanged, true);
            app.CheckBox_GPipostparietal.Text = 'GPi postparietal';
            app.CheckBox_EXA = uicheckbox(app.AtlasPanel);
            app.CheckBox_EXA.ValueChangedFcn = createCallbackFcn(app, @CheckBox_EXAValueChanged, true);
            app.CheckBox_EXA.Text = 'EXA';
            app.CheckBox_HN = uicheckbox(app.AtlasPanel);
            app.CheckBox_HN.ValueChangedFcn = createCallbackFcn(app, @CheckBox_HNValueChanged, true);
            app.CheckBox_HN.Text = 'HN';
            app.CheckBox_HTH = uicheckbox(app.AtlasPanel);
            app.CheckBox_HTH.ValueChangedFcn = createCallbackFcn(app, @CheckBox_HTHValueChanged, true);
            app.CheckBox_HTH.Text = 'HTH';
            app.CheckBox_SNr = uicheckbox(app.AtlasPanel);
            app.CheckBox_SNr.ValueChangedFcn = createCallbackFcn(app, @CheckBox_SNrValueChanged, true);
            app.CheckBox_SNr.Text = 'SNr';
            app.CheckBox_GPe1 = uicheckbox(app.AtlasPanel);
            app.CheckBox_GPe1.ValueChangedFcn = createCallbackFcn(app, @CheckBox_GPe1ValueChanged, true);
            app.CheckBox_GPe1.Text = 'GPe1';
            app.CheckBox_GPe2 = uicheckbox(app.AtlasPanel);
            app.CheckBox_GPe2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_GPe2ValueChanged, true);
            app.CheckBox_GPe2.Text = 'GPe2';
            app.CheckBox_SNc = uicheckbox(app.AtlasPanel);
            app.CheckBox_SNc.ValueChangedFcn = createCallbackFcn(app, @CheckBox_SNcValueChanged, true);
            app.CheckBox_SNc.Text = 'SNc';
            app.CheckBox_RN1 = uicheckbox(app.AtlasPanel);
            app.CheckBox_RN1.ValueChangedFcn = createCallbackFcn(app, @CheckBox_RN1ValueChanged, true);
            app.CheckBox_RN1.Text = 'RN1';
            app.CheckBox_GPioccipital = uicheckbox(app.AtlasPanel);
            app.CheckBox_GPioccipital.ValueChangedFcn = createCallbackFcn(app, @CheckBox_GPioccipitalValueChanged, true);
            app.CheckBox_GPioccipital.Text = 'GPi occipital';
            app.CheckBox_GPitemporal = uicheckbox(app.AtlasPanel);
            app.CheckBox_GPitemporal.ValueChangedFcn = createCallbackFcn(app, @CheckBox_GPitemporalValueChanged, true);
            app.CheckBox_GPitemporal.Text = 'GPi temporal';
            app.CheckBox_GPiprefrontal = uicheckbox(app.AtlasPanel);
            app.CheckBox_GPiprefrontal.ValueChangedFcn = createCallbackFcn(app, @CheckBox_GPiprefrontalValueChanged, true);
            app.CheckBox_GPiprefrontal.Text = 'GPi prefrontal';
            app.CheckBox_VTA = uicheckbox(app.AtlasPanel);
            app.CheckBox_VTA.ValueChangedFcn = createCallbackFcn(app, @CheckBox_VTAValueChanged, true);
            app.CheckBox_VTA.Text = 'VTA';
            app.CheckBox_VPe = uicheckbox(app.AtlasPanel);
            app.CheckBox_VPe.ValueChangedFcn = createCallbackFcn(app, @CheckBox_VPeValueChanged, true);
            app.CheckBox_VPe.Text = 'VPe';
            app.CheckBox_MN = uicheckbox(app.AtlasPanel);
            app.CheckBox_MN.ValueChangedFcn = createCallbackFcn(app, @CheckBox_MNValueChanged, true);
            app.CheckBox_MN.Text = 'MN';
            app.CheckBox_PBP = uicheckbox(app.AtlasPanel);
            app.CheckBox_PBP.ValueChangedFcn = createCallbackFcn(app, @CheckBox_PBPValueChanged, true);
            app.CheckBox_PBP.Text = 'PBP';
            app.CheckBox_ALL = uicheckbox(app.AtlasPanel);
            app.CheckBox_ALL.ValueChangedFcn = createCallbackFcn(app, @CheckBox_ALLValueChanged, true);
            app.CheckBox_ALL.Text = 'ALL';
        end
    end

    methods (Access = public)

        % Construct app
        function app = AtlasCIT168(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end