classdef FastField < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Menu                           matlab.ui.container.Menu
        FastFieldLabel                 matlab.ui.control.Label
        parametersPanel                matlab.ui.container.Panel
        AmplitudemASliderLabel         matlab.ui.control.Label
        amp_slider                     matlab.ui.control.Slider
        amp_box                        matlab.ui.control.NumericEditField
        ConductivitySmSliderLabel      matlab.ui.control.Label
        cond_slider                    matlab.ui.control.Slider
        cond_box                       matlab.ui.control.NumericEditField
        StimulationNameEditFieldLabel  matlab.ui.control.Label
        stim_name                      matlab.ui.control.EditField
        ButtonGroup                    matlab.ui.container.ButtonGroup
        right_side                     matlab.ui.control.RadioButton
        left_side                      matlab.ui.control.RadioButton
        SideDropDownLabel_2            matlab.ui.control.Label
        ElectrodeTypeLabel             matlab.ui.control.Label
        electrode_type                 matlab.ui.control.DropDown
        atlas_button                   matlab.ui.control.Button
        SideDropDownLabel_3            matlab.ui.control.Label
        btnApproximateFromPW           matlab.ui.control.Button
        inputEFieldThreshold           matlab.ui.control.NumericEditField
        patient_path                   matlab.ui.control.Button
        fastfield_path                 matlab.ui.control.Button
        axes_el                        matlab.ui.control.UIAxes
        ContactSPanel                  matlab.ui.container.Panel
        con8_per                       matlab.ui.control.Label
        Contact8Label                  matlab.ui.control.Label
        con8                           matlab.ui.control.NumericEditField
        Contact7Label                  matlab.ui.control.Label
        con7                           matlab.ui.control.NumericEditField
        Contact6Label                  matlab.ui.control.Label
        con6                           matlab.ui.control.NumericEditField
        Contact5Label                  matlab.ui.control.Label
        con5                           matlab.ui.control.NumericEditField
        Contact4Label                  matlab.ui.control.Label
        con4                           matlab.ui.control.NumericEditField
        Contact3Label                  matlab.ui.control.Label
        con3                           matlab.ui.control.NumericEditField
        Contact2Label                  matlab.ui.control.Label
        con2                           matlab.ui.control.NumericEditField
        Contact1Label                  matlab.ui.control.Label
        con1                           matlab.ui.control.NumericEditField
        con7_per                       matlab.ui.control.Label
        con6_per                       matlab.ui.control.Label
        con5_per                       matlab.ui.control.Label
        con4_per                       matlab.ui.control.Label
        con3_per                       matlab.ui.control.Label
        con2_per                       matlab.ui.control.Label
        con1_per                       matlab.ui.control.Label
        axes_result                    matlab.ui.control.UIAxes
        stim_button                    matlab.ui.control.Button
        ErrorLabel                     matlab.ui.control.Label
        error_box                      matlab.ui.control.EditField
        LightSwitchLabel               matlab.ui.control.Label
        LightSwitch                    matlab.ui.control.Switch
    end

    
    properties (Access = private)
        ele = [];
        el_value=[];
        std_ef = [];
        grid_v = [];
        el_model = [];
        el_pat = [];
        atl = [];
        error_text = [];

        uiFigSelect = [];
        camlightLeft = []; % Handle to camlight left
        camlightRight = []; % Handle to camlight left
        camlightHeadlight= []; % Handle to camlight left

        
        eFieldThreshold = 0.2;  % [V/mm] the threshold for the VTA, do be get by approximaiton gui later
    end
    
    methods (Access = private)
        
        function res = func(app)
            app.error_box.Value  ='it worked';
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, arg1)
            app.fastfield_path.Text = fileparts(mfilename('fullpath'));
            app.electrode_typeValueChanged([]);
        end

        % Button pushed function: patient_path
        function patient_pathButtonPushed(app, event)
             path_pat = uigetdir();
            %path_pat= '/Users/mehri.baniasadi/Desktop/SelectedPatients/BOSTON/96376/DistalPacer96376/output/dbsar2leaddbs';
            dir_patient = path_pat;
            app.patient_path.Text = path_pat ;
            
            reco = load([dir_patient filesep 'ea_reconstruction.mat']);
            electrode_patient = reco.reco.mni;
            app.el_pat = electrode_patient;
            app.electrode_typeValueChanged([]); % update electrode view

            
        end

        % Button pushed function: fastfield_path
        function fastfield_pathButtonPushed(app, event)
             path_ff = uigetdir();
            %path_ff='/Users/mehri.baniasadi/Documents/GitHub/FastField';
            dir_fastfield = fileparts(mfilename('fullpath'));
            app.fastfield_path.Text = path_ff ;

        end

        % Value changed function: electrode_type
        function electrode_typeValueChanged(app, event)
            
            if strcmp(app.fastfield_path.Text,'Choose FastField Directory')
                app.error_box.Value = 'Please choose the FastField directory';
            else
                app.error_box.Value = '';
                if strcmp(app.patient_path.Text,'Choose Patient Directory')
                    app.error_box.Value = 'Please choose a Patient directory';
                else
                    app.error_box.Value = '';
                    dir_patient = app.patient_path.Text;
                    
                    dir_fastfield = app.fastfield_path.Text;
                    
                    new_el_value = app.electrode_type.Value;
                    old_el_value = app.el_value;
                    Electrode_type = new_el_value;
                    
                    if strcmp(new_el_value, old_el_value)
                        if isempty(app.std_ef)
                            [standard_efield,grid_vec,electrode,electrode_patient,atlases] = load_files(dir_fastfield,dir_patient,Electrode_type);
                            app.std_ef = standard_efield;
                            app.grid_v = grid_vec;
                            app.el_model = electrode;
                            app.el_pat = electrode_patient;
                            app.atl = atlases;
                        end
                        
                        if isempty(app.ele)
                            app.ele = load([dir_fastfield filesep 'resources' filesep 'for_gui' filesep new_el_value '_elfv1.mat']);
                        end
                    else
                        
                        app.ele = load([dir_fastfield filesep 'resources' filesep 'for_gui' filesep new_el_value '_elfv1.mat']);
                        app.el_value = new_el_value;
                        
                        [standard_efield,grid_vec,electrode,electrode_patient,atlases] = load_files(dir_fastfield,dir_patient,Electrode_type);
                        app.std_ef = standard_efield;
                        app.grid_v = grid_vec;
                        app.el_model = electrode;
                        app.el_pat = electrode_patient;
                        app.atl = atlases;
                        
                        elfv1 = app.ele.elfv1;
                        l_elfv1 = length(elfv1);
                        
                        if l_elfv1 < 5
                            app.con5.Enable = 'off';
                            app.con6.Enable = 'off';
                            app.con7.Enable = 'off';
                            app.con8.Enable = 'off';
                            
                            app.con5.Value = 0;
                            app.con6.Value = 0;
                            app.con7.Value = 0;
                            app.con8.Value = 0;
                            
                            app.con5.FontColor = [0.1 0.1 0.1];
                            app.Contact5Label.FontColor = [0.1 0.1 0.1];
                            app.con5_per.FontColor = [0.1 0.1 0.1];
                            
                            app.con6.FontColor = [0.1 0.1 0.1];
                            app.Contact6Label.FontColor = [0.1 0.1 0.1];
                            app.con6_per.FontColor = [0.1 0.1 0.1];
                            
                            app.con7.FontColor = [0.1 0.1 0.1];
                            app.Contact7Label.FontColor = [0.1 0.1 0.1];
                            app.con7_per.FontColor = [0.1 0.1 0.1];
                            
                            
                            app.con8.FontColor = [0.1 0.1 0.1];
                            app.Contact8Label.FontColor = [0.1 0.1 0.1];
                            app.con8_per.FontColor = [0.1 0.1 0.1];
                        else
                            app.con5.Enable = 'on';
                            app.con6.Enable = 'on';
                            app.con7.Enable = 'on';
                            app.con8.Enable = 'on';
                        end
                        
                    end
                    
                    
                    cla(app.axes_el)
                    
                    elfv1=app.ele.elfv1;
                    
                    for i=1:length(elfv1)
                        
                        patch(elfv1(i), 'FaceColor', rgb('dimgray'), 'EdgeColor','none', 'FaceAlpha', 0.3,'parent', app.axes_el)
                        
                        pointt=mean(elfv1(i).vertices);
                        if i<9
                            t1=text(pointt(1)+0.2,pointt(2),pointt(3)+0.25,num2str(i),'parent', app.axes_el);
                            t1.Color=[0 0 0];
                            t1.FontSize=18;
                            t1.FontWeight = 'Bold';
                            
                        elseif i==9
                            
                            t1=text(pointt(1)+0.2,pointt(2),pointt(3),'M','parent', app.axes_el);
                            t1.Color=[0 0 0];
                            t1.FontSize=18;
                            t1.FontWeight = 'Bold';
                        end
                        
                    end
                    rotate3d(app.axes_el,'on')
                    app.axes_el.View = [180 40];
                    app.axes_el.CameraPositionMode = 'manual';
                    app.axes_el.CameraPosition = [0 60 60];
                    app.axes_el.DataAspectRatioMode ='manual' ;
                    app.axes_el.DataAspectRatio =[1 1 1];
                    app.axes_el.CameraViewAngleMode = 'manual';
                    
                    if strcmp(new_el_value, 'boston_vercise') || strcmp(new_el_value, 'medtronic_3391')
                        app.axes_el.CameraViewAngle = 9;
                    else
                        app.axes_el.CameraViewAngle = 6;
                    end
                    
                    stim_buttonButtonPushed(app, event)
                end
            end
        end

        % Value changed function: con8
        function con8ValueChanged(app, event)
            value = app.con8.Value;
            dir_fastfield = app.fastfield_path.Text;
            value_el = num2str(app.electrode_type.Value);
            
            if isempty(app.ele)
                app.ele = load([dir_fastfield filesep 'resources' filesep 'for_gui' filesep value_el '_elfv1.mat']);
            end
            
            elfv1=app.ele.elfv1;
            pointt=mean(elfv1(8).vertices);
            
            if value > 0
                app.Contact8Label.FontColor = [0.1 0.55 0.6];
                app.con8.FontColor = [0.1 0.55 0.6];
                app.con8_per.FontColor = [0.1 0.55 0.6];
                
                t1=text(pointt(1)+0.2,pointt(2),pointt(3)+0.25,num2str(8),'parent', app.axes_el);
                t1.Color=[0.1 0.55 0.6];
                t1.FontSize=18;
                t1.FontWeight = 'Bold';
                
            else
                app.con8.FontColor = [0.1 0.1 0.1];
                app.Contact8Label.FontColor = [0.1 0.1 0.1];
                app.con8_per.FontColor = [0.1 0.1 0.1];
                
                t1=text(pointt(1)+0.2,pointt(2),pointt(3)+0.25,num2str(8),'parent', app.axes_el);
                t1.Color=[0 0 0];
                t1.FontSize=18;
                t1.FontWeight = 'Bold';
            end
            stim_buttonButtonPushed(app, event)
        end

        % Value changed function: con7
        function con7ValueChanged(app, event)
            value = app.con7.Value;
            dir_fastfield = app.fastfield_path.Text;
            value_el = num2str(app.electrode_type.Value);
            
            if isempty(app.ele)
                app.ele = load([dir_fastfield filesep 'resources' filesep 'for_gui' filesep value_el '_elfv1.mat']);
            end
            
            elfv1=app.ele.elfv1;
            pointt=mean(elfv1(7).vertices);
            
            if value > 0
                app.Contact7Label.FontColor = [0.1 0.55 0.6];
                app.con7.FontColor = [0.1 0.55 0.6];
                app.con7_per.FontColor = [0.1 0.55 0.6];
                
                t1=text(pointt(1)+0.2,pointt(2),pointt(3)+0.25,num2str(7),'parent', app.axes_el);
                t1.Color=[0.1 0.55 0.6];
                t1.FontSize=18;
                t1.FontWeight = 'Bold';
                
            else
                app.con7.FontColor = [0.1 0.1 0.1];
                app.Contact7Label.FontColor = [0.1 0.1 0.1];
                app.con7_per.FontColor = [0.1 0.1 0.1];
                
                t1=text(pointt(1)+0.2,pointt(2),pointt(3)+0.25,num2str(7),'parent', app.axes_el);
                t1.Color=[0 0 0];
                t1.FontSize=18;
                t1.FontWeight = 'Bold';
            end
            stim_buttonButtonPushed(app, event)
        end

        % Value changed function: con6
        function con6ValueChanged(app, event)
            value = app.con6.Value;
            dir_fastfield = app.fastfield_path.Text;
            value_el = num2str(app.electrode_type.Value);
            
            if isempty(app.ele)
                app.ele = load([dir_fastfield filesep 'resources' filesep 'for_gui' filesep value_el '_elfv1.mat']);
            end
            
            elfv1=app.ele.elfv1;
            pointt=mean(elfv1(6).vertices);
            
            if value > 0
                app.Contact6Label.FontColor = [0.1 0.55 0.6];
                app.con6.FontColor = [0.1 0.55 0.6];
                app.con6_per.FontColor = [0.1 0.55 0.6];
                
                t1=text(pointt(1)+0.2,pointt(2),pointt(3)+0.25,num2str(6),'parent', app.axes_el);
                t1.Color=[0.1 0.55 0.6];
                t1.FontSize=18;
                t1.FontWeight = 'Bold';
                
            else
                app.con6.FontColor = [0.1 0.1 0.1];
                app.Contact6Label.FontColor = [0.1 0.1 0.1];
                app.con6_per.FontColor = [0.1 0.1 0.1];
                
                t1=text(pointt(1)+0.2,pointt(2),pointt(3)+0.25,num2str(6),'parent', app.axes_el);
                t1.Color=[0 0 0];
                t1.FontSize=18;
                t1.FontWeight = 'Bold';
            end
            stim_buttonButtonPushed(app, event)
        end

        % Value changed function: con5
        function con5ValueChanged(app, event)
            value = app.con5.Value;
            dir_fastfield = app.fastfield_path.Text;
            value_el = num2str(app.electrode_type.Value);
            
            if isempty(app.ele)
                app.ele = load([dir_fastfield filesep 'resources' filesep 'for_gui' filesep value_el '_elfv1.mat']);
            end
            
            elfv1=app.ele.elfv1;
            pointt=mean(elfv1(5).vertices);
            
            if value > 0
                app.Contact5Label.FontColor = [0.1 0.55 0.6];
                app.con5.FontColor = [0.1 0.55 0.6];
                app.con5_per.FontColor = [0.1 0.55 0.6];
                
                t1=text(pointt(1)+0.2,pointt(2),pointt(3)+0.25,num2str(5),'parent', app.axes_el);
                t1.Color=[0.1 0.55 0.6];
                t1.FontSize=18;
                t1.FontWeight = 'Bold';
                
            else
                app.con5.FontColor = [0.1 0.1 0.1];
                app.Contact5Label.FontColor = [0.1 0.1 0.1];
                app.con5_per.FontColor = [0.1 0.1 0.1];
                
                t1=text(pointt(1)+0.2,pointt(2),pointt(3)+0.25,num2str(5),'parent', app.axes_el);
                t1.Color=[0 0 0];
                t1.FontSize=18;
                t1.FontWeight = 'Bold';
            end
            stim_buttonButtonPushed(app, event)
        end

        % Value changed function: con4
        function con4ValueChanged(app, event)
            value = app.con4.Value;
            dir_fastfield = app.fastfield_path.Text;
            value_el = num2str(app.electrode_type.Value);
            
            if isempty(app.ele)
                app.ele = load([dir_fastfield filesep 'resources' filesep 'for_gui' filesep value_el '_elfv1.mat']);
            end
            
            elfv1=app.ele.elfv1;
            pointt=mean(elfv1(4).vertices);
            
            if value > 0
                app.Contact4Label.FontColor = [0.1 0.55 0.6];
                app.con4.FontColor = [0.1 0.55 0.6];
                app.con4_per.FontColor = [0.1 0.55 0.6];
                
                t1=text(pointt(1)+0.2,pointt(2),pointt(3)+0.25,num2str(4),'parent', app.axes_el);
                t1.Color=[0.1 0.55 0.6];
                t1.FontSize=18;
                t1.FontWeight = 'Bold';
                
            else
                app.con4.FontColor = [0.1 0.1 0.1];
                app.Contact4Label.FontColor = [0.1 0.1 0.1];
                app.con4_per.FontColor = [0.1 0.1 0.1];
                
                t1=text(pointt(1)+0.2,pointt(2),pointt(3)+0.25,num2str(4),'parent', app.axes_el);
                t1.Color=[0 0 0];
                t1.FontSize=18;
                t1.FontWeight = 'Bold';
            end
            stim_buttonButtonPushed(app, event)
        end

        % Value changed function: con3
        function con3ValueChanged(app, event)
            value = app.con3.Value;
            dir_fastfield = app.fastfield_path.Text;
            value_el = num2str(app.electrode_type.Value);
            
            if isempty(app.ele)
                app.ele = load([dir_fastfield filesep 'resources' filesep 'for_gui' filesep value_el '_elfv1.mat']);
            end
            
            elfv1=app.ele.elfv1;
            pointt=mean(elfv1(3).vertices);
            
            if value > 0
                app.Contact3Label.FontColor = [0.1 0.55 0.6];
                app.con3.FontColor = [0.1 0.55 0.6];
                app.con3_per.FontColor = [0.1 0.55 0.6];
                
                t1=text(pointt(1)+0.2,pointt(2),pointt(3)+0.25,num2str(3),'parent', app.axes_el);
                t1.Color=[0.1 0.55 0.6];
                t1.FontSize=18;
                t1.FontWeight = 'Bold';
                
            else
                app.con3.FontColor = [0.1 0.1 0.1];
                app.Contact3Label.FontColor = [0.1 0.1 0.1];
                app.con3_per.FontColor = [0.1 0.1 0.1];
                
                t1=text(pointt(1)+0.2,pointt(2),pointt(3)+0.25,num2str(3),'parent', app.axes_el);
                t1.Color=[0 0 0];
                t1.FontSize=18;
                t1.FontWeight = 'Bold';
            end
            stim_buttonButtonPushed(app, event)
        end

        % Value changed function: con2
        function con2ValueChanged(app, event)
            value = app.con2.Value;
            dir_fastfield = app.fastfield_path.Text;
            value_el = num2str(app.electrode_type.Value);
            
            if isempty(app.ele)
                app.ele = load([dir_fastfield filesep 'resources' filesep 'for_gui' filesep value_el '_elfv1.mat']);
            end
            
            elfv1=app.ele.elfv1;
            pointt=mean(elfv1(2).vertices);
            
            if value > 0
                app.Contact2Label.FontColor = [0.1 0.55 0.6];
                app.con2.FontColor = [0.1 0.55 0.6];
                app.con2_per.FontColor = [0.1 0.55 0.6];
                
                t1=text(pointt(1)+0.2,pointt(2),pointt(3)+0.25,num2str(2),'parent', app.axes_el);
                t1.Color=[0.1 0.55 0.6];
                t1.FontSize=18;
                t1.FontWeight = 'Bold';
                
            else
                app.con2.FontColor = [0.1 0.1 0.1];
                app.Contact2Label.FontColor = [0.1 0.1 0.1];
                app.con2_per.FontColor = [0.1 0.1 0.1];
                
                t1=text(pointt(1)+0.2,pointt(2),pointt(3)+0.25,num2str(2),'parent', app.axes_el);
                t1.Color=[0 0 0];
                t1.FontSize=18;
                t1.FontWeight = 'Bold';
            end
            stim_buttonButtonPushed(app, event)
        end

        % Value changed function: con1
        function con1ValueChanged(app, event)
            value = app.con1.Value;
            dir_fastfield = app.fastfield_path.Text;
            value_el = num2str(app.electrode_type.Value);
            
            if isempty(app.ele)
                app.ele = load([dir_fastfield filesep 'resources' filesep 'for_gui' filesep value_el '_elfv1.mat']);
            end
            
            elfv1=app.ele.elfv1;
            pointt=mean(elfv1(1).vertices);
            
            if value > 0
                app.Contact1Label.FontColor = [0.1 0.55 0.6];
                app.con1.FontColor = [0.1 0.55 0.6];
                app.con1_per.FontColor = [0.1 0.55 0.6];
                
                t1=text(pointt(1)+0.2,pointt(2),pointt(3)+0.25,num2str(1),'parent', app.axes_el);
                t1.Color=[0.1 0.55 0.6];
                t1.FontSize=18;
                t1.FontWeight = 'Bold';
                
            else
                app.con1.FontColor = [0.1 0.1 0.1];
                app.Contact1Label.FontColor = [0.1 0.1 0.1];
                app.con1_per.FontColor = [0.1 0.1 0.1];
                
                t1=text(pointt(1)+0.2,pointt(2),pointt(3)+0.25,num2str(1),'parent', app.axes_el);
                t1.Color=[0 0 0];
                t1.FontSize=18;
                t1.FontWeight = 'Bold';
            end
            stim_buttonButtonPushed(app, event)
        end

        % Button pushed function: stim_button
        function stim_buttonButtonPushed(app, event)
            if ~strcmp(app.fastfield_path.Text,'Choose FastField Directory')
                if ~strcmp(app.patient_path.Text,'Choose Patient Directory')
                    app.error_box.Value = '';
                end
            end
            dir_patient = app.patient_path.Text;
            dir_fastfield = app.fastfield_path.Text;
            
            amp = app.amp_slider.Value;
            app.amp_box.Value = amp;
            
            conductivity = app.cond_slider.Value;
            app.cond_box.Value = conductivity;
            
            target = '';
            %target = num2str(app.targetchoose.Value);
            if app.right_side.Value
                side = 1;
            elseif app.left_side.Value
                side = 2;
            end
            
            Electrode_type = num2str(app.electrode_type.Value);
            Stim_label = num2str(app.stim_name.Value);
            
            p1 = app.con1.Value;
            p2 = app.con2.Value;
            p3 = app.con3.Value;
            p4 = app.con4.Value;
            p5 = app.con5.Value;
            p6 = app.con6.Value;
            p7 = app.con7.Value;
            p8 = app.con8.Value;
            perc = [p1,p2,p3,p4,p5,p6,p7,p8];
            
            standard_efield = app.std_ef;
            grid_vec = app.grid_v;
            electrode = app.el_model;
            electrode_patient = app.el_pat;
            atlases = app.atl;
            
            if sum(perc) < 100.9 && sum(perc) > 99.1  || sum(perc)==0
                                                                  
                [Efield,xg,yg,zg,elfv,~] = fastfield_main(standard_efield,grid_vec,electrode,electrode_patient,perc,amp,side,conductivity);
                app.error_box.Value = '';
                
                
                cla(app.axes_result)
                perc(9)=0;
                
                for i=1:length(elfv)
                    
                    if (ismember(i,[1:length(electrode.contacts)]))
                        if perc(i)>0
                            patch(elfv(i), 'FaceColor', [0.9 0.7 0.1] , 'EdgeColor', 'none','parent', app.axes_result)
                            
                        else
                            patch(elfv(i), 'FaceColor', rgb('dimgray'), 'EdgeColor', 'none','parent', app.axes_result)
                            
                        end
                    elseif (ismember(i, [length(electrode.contacts)+1:length(electrode.contacts)+length(electrode.insulation)]))
                        patch(elfv(i), 'FaceColor',rgb('lightgray'), 'EdgeColor', 'none','parent', app.axes_result)
                        
                    end
                end
                rotate3d(app.axes_result,'on')
                app.axes_result.XTick = [];
                app.axes_result.YTick = [];
                app.axes_result.ZTick = [];
                app.axes_result.XColor = 'none';
                app.axes_result.YColor = 'none';
                app.axes_result.ZColor = 'none';
                app.axes_result.Title.String = 'Electric Field';
                app.axes_result.Color = [0.95 0.95 0.95];
                app.axes_result.CameraViewAngleMode = 'manual';
                app.axes_result.CameraViewAngle = 8;
                app.axes_result.DataAspectRatioMode ='manual' ;
                app.axes_result.DataAspectRatio =[1 1 1];
                
                cut = 1:100;
                
                data = Efield(:,:,cut);
                X=xg;
                Y=yg;
                Z=zg;
                
                hold(app.axes_result,'on');
                s = isosurface(X(:,:,cut), Y(:,:,cut), Z(:,:,cut), data > (app.eFieldThreshold * 1000) );
                
                patch(s, 'EdgeColor', 'none', 'FaceColor', [1 0 0] , 'FaceAlpha', 0.35, 'Parent', app.axes_result);

            else
                app.error_box.Value = 'Sum of the contacts should be equal to 100';
                app.error_box.Editable = 'off';
                app.error_box.FontColor = [0.9 0.2 0.2];
                
            end
            
            
            if ~isempty(app.uiFigSelect)
            %%%%%%%%%%%%%%%%%%%%%%%%%
            if app.uiFigSelect.CheckBox_ALL.Value
                struc_brain = ones(35,1);
                struc_brain_name{35} = app.uiFigSelect.CheckBox_VOICM.Text;
                struc_brain_name{34} = app.uiFigSelect.CheckBox_VIM.Text;
                struc_brain_name{33} = app.uiFigSelect.CheckBox_VA.Text;
                struc_brain_name{32} = app.uiFigSelect.CheckBox_Th.Text;
                struc_brain_name{31} = app.uiFigSelect.CheckBox_GPe.Text;
                struc_brain_name{30} = app.uiFigSelect.CheckBox_RN2.Text;
                struc_brain_name{29} = app.uiFigSelect.CheckBox_GPiprefrontal.Text;
                struc_brain_name{28} = app.uiFigSelect.CheckBox_GPitemporal.Text;
                struc_brain_name{27} = app.uiFigSelect.CheckBox_GPioccipital.Text;
                struc_brain_name{26} = app.uiFigSelect.CheckBox_GPipostparietal.Text;
                struc_brain_name{25} = app.uiFigSelect.CheckBox_GPisensory.Text;
                struc_brain_name{24} = app.uiFigSelect.CheckBox_GPipremotor.Text;
                struc_brain_name{23} = app.uiFigSelect.CheckBox_GPiprimarymotor.Text;
                struc_brain_name{22} = app.uiFigSelect.CheckBox_GPisensorimotor.Text;
                struc_brain_name{21} = app.uiFigSelect.CheckBox_GPi2.Text;
                struc_brain_name{20} = app.uiFigSelect.CheckBox_STN2limbic.Text;
                struc_brain_name{19} = app.uiFigSelect.CheckBox_STN2associative.Text;
                struc_brain_name{18} = app.uiFigSelect.CheckBox_STN2motor.Text;
                struc_brain_name{17} = app.uiFigSelect.CheckBox_STN2.Text;
                struc_brain_name{16} = app.uiFigSelect.CheckBox_VPe.Text;
                struc_brain_name{15} = app.uiFigSelect.CheckBox_VTA.Text;
                struc_brain_name{14} = app.uiFigSelect.CheckBox_STN1.Text;
                struc_brain_name{13} = app.uiFigSelect.CheckBox_SNr.Text;
                struc_brain_name{12} = app.uiFigSelect.CheckBox_SNc.Text;
                struc_brain_name{11} = app.uiFigSelect.CheckBox_RN1.Text;
                struc_brain_name{10} = app.uiFigSelect.CheckBox_Pu.Text;
                struc_brain_name{9} = app.uiFigSelect.CheckBox_PBP.Text;
                struc_brain_name{8} = app.uiFigSelect.CheckBox_NAC.Text;
                struc_brain_name{7} = app.uiFigSelect.CheckBox_MN.Text;
                struc_brain_name{6} = app.uiFigSelect.CheckBox_HTH.Text;
                struc_brain_name{5} = app.uiFigSelect.CheckBox_HN.Text;
                struc_brain_name{4} = app.uiFigSelect.CheckBox_GPi1.Text;
                struc_brain_name{3} = app.uiFigSelect.CheckBox_GPe.Text;
                struc_brain_name{2} = app.uiFigSelect.CheckBox_EXA.Text;
                struc_brain_name{1} = app.uiFigSelect.CheckBox_Ca.Text;
                
            else
                if app.uiFigSelect.CheckBox_Ca.Value
                    struc_brain(1)=1;
                    struc_brain_name{1} = app.uiFigSelect.CheckBox_Ca.Text;
                else
                    struc_brain(1)=0;
                end
                
                if app.uiFigSelect.CheckBox_EXA.Value
                    struc_brain(2)=1;
                    struc_brain_name{2} = app.uiFigSelect.CheckBox_EXA.Text;
                else
                    struc_brain(2)=0;
                end
                
                if app.uiFigSelect.CheckBox_GPe.Value
                    struc_brain(3)=1;
                    struc_brain_name{3} = app.uiFigSelect.CheckBox_GPe.Text;
                else
                    struc_brain(3)=0;
                end
                
                if app.uiFigSelect.CheckBox_GPi1.Value
                    struc_brain(4)=1;
                    struc_brain_name{4} = app.uiFigSelect.CheckBox_GPi1.Text;
                else
                    struc_brain(4)=0;
                end
                
                if app.uiFigSelect.CheckBox_HN.Value
                    struc_brain(5)=1;
                    struc_brain_name{5} = app.uiFigSelect.CheckBox_HN.Text;
                else
                    struc_brain(5)=0;
                end
                if app.uiFigSelect.CheckBox_HTH.Value
                    struc_brain(6)=1;
                    struc_brain_name{6} = app.uiFigSelect.CheckBox_HTH.Text;
                else
                    struc_brain(6)=0;
                end
                
                if app.uiFigSelect.CheckBox_MN.Value
                    struc_brain(7)=1;
                    struc_brain_name{7} = app.uiFigSelect.CheckBox_MN.Text;
                else
                    struc_brain(7)=0;
                end
                
                if app.uiFigSelect.CheckBox_NAC.Value
                    struc_brain(8)=1;
                    struc_brain_name{8} = app.uiFigSelect.CheckBox_NAC.Text;
                else
                    struc_brain(8)=0;
                end
                
                if app.uiFigSelect.CheckBox_PBP.Value
                    struc_brain(9)=1;
                    struc_brain_name{9} = app.uiFigSelect.CheckBox_PBP.Text;
                else
                    struc_brain(9)=0;
                end
                
                if app.uiFigSelect.CheckBox_Pu.Value
                    struc_brain(10)=1;
                    struc_brain_name{10} = app.uiFigSelect.CheckBox_Pu.Text;
                else
                    struc_brain(10)=0;
                end
                
                if app.uiFigSelect.CheckBox_RN1.Value
                    struc_brain(11)=1;
                    struc_brain_name{11} = app.uiFigSelect.CheckBox_RN1.Text;
                else
                    struc_brain(11)=0;
                end
                
                if app.uiFigSelect.CheckBox_SNc.Value
                    struc_brain(12)=1;
                    struc_brain_name{12} = app.uiFigSelect.CheckBox_SNc.Text;
                else
                    struc_brain(12)=0;
                end
                
                if app.uiFigSelect.CheckBox_SNr.Value
                    struc_brain(13)=1;
                    struc_brain_name{13} = app.uiFigSelect.CheckBox_SNr.Text;
                else
                    struc_brain(13)=0;
                end
                
                if app.uiFigSelect.CheckBox_STN1.Value
                    struc_brain(14)=1;
                    struc_brain_name{14} = app.uiFigSelect.CheckBox_STN1.Text;
                else
                    struc_brain(14)=0;
                end
                
                if app.uiFigSelect.CheckBox_VTA.Value
                    struc_brain(15)=1;
                    struc_brain_name{15} = app.uiFigSelect.CheckBox_VTA.Text;
                else
                    struc_brain(15)=0;
                end
                if app.uiFigSelect.CheckBox_VPe.Value
                    struc_brain(16)=1;
                    struc_brain_name{16} = app.uiFigSelect.CheckBox_VPe.Text;
                else
                    struc_brain(16)=0;
                end
                
                if app.uiFigSelect.CheckBox_STN2.Value
                    struc_brain(17)=1;
                    struc_brain_name{17} = app.uiFigSelect.CheckBox_STN2.Text;
                else
                    struc_brain(17)=0;
                end
                
                if app.uiFigSelect.CheckBox_STN2motor.Value
                    struc_brain(18)=1;
                    struc_brain_name{18} = app.uiFigSelect.CheckBox_STN2motor.Text;
                else
                    struc_brain(18)=0;
                end
                
                if app.uiFigSelect.CheckBox_STN2associative.Value
                    struc_brain(19)=1;
                    struc_brain_name{19} = app.uiFigSelect.CheckBox_STN2associative.Text;
                else
                    struc_brain(19)=0;
                end
                
                if app.uiFigSelect.CheckBox_STN2limbic.Value
                    struc_brain(20)=1;
                    struc_brain_name{20} = app.uiFigSelect.CheckBox_STN2limbic.Text;
                else
                    struc_brain(20)=0;
                end
                if app.uiFigSelect.CheckBox_GPi2.Value
                    struc_brain(21)=1;
                    struc_brain_name{21} = app.uiFigSelect.CheckBox_GPi2.Text;
                else
                    struc_brain(21)=0;
                end
                
                if app.uiFigSelect.CheckBox_GPisensorimotor.Value
                    struc_brain(22)=1;
                    struc_brain_name{22} = app.uiFigSelect.CheckBox_GPisensorimotor.Text;
                else
                    struc_brain(22)=0;
                end
                
                if app.uiFigSelect.CheckBox_GPiprimarymotor.Value
                    struc_brain(23)=1;
                    struc_brain_name{23} = app.uiFigSelect.CheckBox_GPiprimarymotor.Text;
                else
                    struc_brain(23)=0;
                end
                
                if app.uiFigSelect.CheckBox_GPipremotor.Value
                    struc_brain(24)=1;
                    struc_brain_name{24} = app.uiFigSelect.CheckBox_GPipremotor.Text;
                else
                    struc_brain(24)=0;
                end
                
                if app.uiFigSelect.CheckBox_GPisensory.Value
                    struc_brain(25)=1;
                    struc_brain_name{25} = app.uiFigSelect.CheckBox_GPisensory.Text;
                else
                    struc_brain(25)=0;
                end
                if app.uiFigSelect.CheckBox_GPipostparietal.Value
                    struc_brain(26)=1;
                    struc_brain_name{26} = app.uiFigSelect.CheckBox_GPipostparietal.Text;
                else
                    struc_brain(26)=0;
                end
                
                if app.uiFigSelect.CheckBox_GPioccipital.Value
                    struc_brain(27)=1;
                    struc_brain_name{27} = app.uiFigSelect.CheckBox_GPioccipital.Text;
                else
                    struc_brain(27)=0;
                end
                
                if app.uiFigSelect.CheckBox_GPitemporal.Value
                    struc_brain(28)=1;
                    struc_brain_name{28} = app.uiFigSelect.CheckBox_GPitemporal.Text;
                else
                    struc_brain(28)=0;
                end
                
                if app.uiFigSelect.CheckBox_GPiprefrontal.Value
                    struc_brain(29)=1;
                    struc_brain_name{29} = app.uiFigSelect.CheckBox_GPiprefrontal.Text;
                else
                    struc_brain(29)=0;
                end
                
                if app.uiFigSelect.CheckBox_RN2.Value
                    struc_brain(30)=1;
                    struc_brain_name{30} = app.uiFigSelect.CheckBox_RN2.Text;
                else
                    struc_brain(30)=0;
                end
                if app.uiFigSelect.CheckBox_GPe.Value
                    struc_brain(31)=1;
                    struc_brain_name{31} = app.uiFigSelect.CheckBox_GPe.Text;
                else
                    struc_brain(31)=0;
                end
                
                if app.uiFigSelect.CheckBox_Th.Value
                    struc_brain(32)=1;
                    struc_brain_name{32} = app.uiFigSelect.CheckBox_Th.Text;
                else
                    struc_brain(32)=0;
                end
                
                if app.uiFigSelect.CheckBox_VA.Value
                    struc_brain(33)=1;
                    struc_brain_name{33} = app.uiFigSelect.CheckBox_VA.Text;
                else
                    struc_brain(33)=0;
                end
                
                if app.uiFigSelect.CheckBox_VIM.Value
                    struc_brain(34)=1;
                    struc_brain_name{34} = app.uiFigSelect.CheckBox_VIM.Text;
                else
                    struc_brain(34)=0;
                end
                
                if app.uiFigSelect.CheckBox_VOICM.Value
                    struc_brain(35)=1;
                    struc_brain_name{35} = app.uiFigSelect.CheckBox_VOICM.Text;
                else
                    struc_brain(35)=0;
                end
                
            end
            
            if isempty(app.atl)
                app.atl = load([dir_fastfield filesep 'resources' filesep 'atlas_combined.mat']);
            end
            
            atlases = app.atl;
            
            hTarget = [];
            
            for kk=1:length(struc_brain)
                
                if struc_brain(kk)==1
                    tar = struc_brain_name{kk};
                    [Ind,] = find(strcmp(tar,atlases.labels{1, 1})==1);
                    
                    if side == 1
                        color_st = atlases.colormap(Ind,:);
                        hTarget(kk) = patch(atlases.fv{Ind,1}, 'FaceColor', color_st, 'EdgeColor', 'none', 'FaceAlpha', 0.45,'parent', app.axes_result);
                       % material dull
                    elseif side == 2
                        color_st = atlases.colormap(Ind,:);
                        hTarget(kk) = patch(atlases.fv{Ind,2}, 'FaceColor', color_st, 'EdgeColor', 'none', 'FaceAlpha', 0.45,'parent', app.axes_result);
                        % material dull
                    end
                else
                    % delete(hTarget(kk))
                end
                
            end
            end
      
            
        end

        % Value changed function: amp_slider
        function amp_sliderValueChanged(app, event)
            value = app.amp_slider.Value;
            app.amp_box.Value = value;
            
            if ~isempty(app.electrode_type.Value)
                if strcmp(app.fastfield_path.Text,'Choose FastField Directory')
                    app.error_box.Value = 'Please choose the FastField directory';
                else
                    app.error_box.Value = '';
                    if strcmp(app.patient_path.Text,'Choose Patient Directory')
                        app.error_box.Value = 'Please choose a Patient directory';
                    else
                        stim_buttonButtonPushed(app, event)
                    end
                end
            end
        end

        % Value changed function: amp_box
        function amp_boxValueChanged(app, event)
            
            value = app.amp_box.Value;
            app.amp_slider.Value = value;
            if ~isempty(app.electrode_type.Value)
                if strcmp(app.fastfield_path.Text,'Choose FastField Directory')
                    app.error_box.Value = 'Please choose the FastField directory';
                else
                    app.error_box.Value = '';
                    if strcmp(app.patient_path.Text,'Choose Patient Directory')
                        app.error_box.Value = 'Please choose a Patient directory';
                    else
                        stim_buttonButtonPushed(app, event)
                    end
                end
            end
        end

        % Value changed function: cond_slider
        function cond_sliderValueChanged(app, event)
            value = app.cond_slider.Value;
            app.cond_box.Value = value;
            if ~isempty(app.electrode_type.Value)
                if strcmp(app.fastfield_path.Text,'Choose FastField Directory')
                    app.error_box.Value = 'Please choose the FastField directory';
                else
                    app.error_box.Value = '';
                    if strcmp(app.patient_path.Text,'Choose Patient Directory')
                        app.error_box.Value = 'Please choose a Patient directory';
                    else
                        stim_buttonButtonPushed(app, event)
                    end
                end
            end
        end

        % Value changed function: cond_box
        function cond_boxValueChanged(app, event)
            value = app.cond_box.Value;
            app.cond_slider.Value = value;
            if ~isempty(app.electrode_type.Value)
                if strcmp(app.fastfield_path.Text,'Choose FastField Directory')
                    app.error_box.Value = 'Please choose the FastField directory';
                else
                    app.error_box.Value = '';
                    if strcmp(app.patient_path.Text,'Choose Patient Directory')
                        app.error_box.Value = 'Please choose a Patient directory';
                    else
                        stim_buttonButtonPushed(app, event)
                    end
                end
            end
        end

        % Callback function
        function error_boxValueChanged(app, event)
            app.error_box.Editable = 'off';
            app.error_box.Enable = 'off';
            value = app.error_box.Value;
            
        end

        % Value changed function: error_box
        function error_boxValueChanged2(app, event)
            
            
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            stim_buttonButtonPushed(app, event)
        end

        % Callback function
        function atlas_nameValueChanged(app, event)
            value = num2str(app.atlas_name.Value);
            if strcmp(value,'CIT168')
                app.uiFigSelect = AtlasCIT168;
                
            end
        end

        % Callback function
        function STN1CheckBoxValueChanged(app, event)
            value = app.STN1CheckBox.Value;
            dir_fastfield = app.fastfield_path.Text;
            hTarget = app.hTarget1;
            
            if value
                if app.right_side.Value
                    side = 1;
                elseif app.left_side.Value
                    side = 2;
                end
                if isempty(app.atl)
                    app.atl = load([dir_fastfield filesep 'resources' filesep 'atlas_combined.mat']);
                end
                % app.atl = app.atl.atlases;
                atlases = app.atl;
                tar = app.STN1CheckBox.Text;
                
                [Ind,] = find(strcmp(tar,atlases.labels{1, 1})==1);
                
                if ~isempty(Ind)
                    %hTarget  = [];
                    if side == 1
                        
                        color_st = app.ccode(app.ll(Ind),:);
                        hTarget = patch(atlases.fv{Ind,1}, 'FaceColor', color_st, 'EdgeColor', 'none', 'FaceAlpha', 0.45,'parent', app.axes_result);
                        material dull
                        %app.axes_result.Children = hTarget;
                        app.hTarget1 = hTarget;
                    elseif side == 2
                        
                        color_st = app.ccode(app.ll(Ind),:);
                        hTarget = patch(atlases.fv{Ind,2}, 'FaceColor', color_st, 'EdgeColor', 'none', 'FaceAlpha', 0.45,'parent', app.axes_result);
                        %app.axes_result.Children = hTarget;
                        app.hTarget1 = hTarget;
                        material dull
                    end
                end
            else
                
                delete(hTarget)
            end
            
        end

        % Button pushed function: atlas_button
        function atlas_buttonButtonPushed(app, event)
            
            app.uiFigSelect = AtlasCIT168;
            
        end

        % Button pushed function: btnApproximateFromPW
        function onBtnApproximateFromPWClicked(app, event)
            f = approxonGui;    
            uiwait(f); % setting thresh
            app.eFieldThreshold = getappdata(f, 'thresh');
            app.inputEFieldThreshold.Value = app.eFieldThreshold;           
            close(f);
            app.stim_buttonButtonPushed([]); % update view
        end

        % Value changed function: inputEFieldThreshold
        function onInputEFieldThresholdClicked(app, event)
            app.eFieldThreshold = app.inputEFieldThreshold.Value;
            app.stim_buttonButtonPushed([]); % update view            
        end

        % Value changed function: LightSwitch
        function LightSwitchValueChanged(app, event)
            value = app.LightSwitch.Value;
            
            switch value
                case 'Off'
                    delete(app.camlightLeft);
                    delete(app.camlightRight);
                    delete(app.camlightHeadlight);
                case 'On'
                    app.camlightLeft = camlight(app.axes_result, 'left');
                    app.camlightRight = camlight(app.axes_result, 'right');
                    app.camlightHeadlight = camlight(app.axes_result, 'headlight');
                    lighting(app.axes_result,'gouraud'); % put gouraud shading computations
                    
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.FastFieldLabel = uilabel(app.UIFigure);
            app.FastFieldLabel.Text = 'FastField';
            app.parametersPanel = uipanel(app.UIFigure);
            app.parametersPanel.Title = 'parameters';
            app.AmplitudemASliderLabel = uilabel(app.parametersPanel);
            app.AmplitudemASliderLabel.Text = 'Amplitude (mA)';
            app.amp_slider = uislider(app.parametersPanel);
            app.amp_slider.Limits = [0 10];
            app.amp_slider.MajorTicks = [];
            app.amp_slider.ValueChangedFcn = createCallbackFcn(app, @amp_sliderValueChanged, true);
            app.amp_slider.MinorTicks = [];
            app.amp_box = uieditfield(app.parametersPanel, 'numeric');
            app.amp_box.ValueChangedFcn = createCallbackFcn(app, @amp_boxValueChanged, true);
            app.ConductivitySmSliderLabel = uilabel(app.parametersPanel);
            app.ConductivitySmSliderLabel.Text = 'Conductivity (S/m)';
            app.cond_slider = uislider(app.parametersPanel);
            app.cond_slider.Limits = [0.01 0.9];
            app.cond_slider.MajorTicks = [];
            app.cond_slider.ValueChangedFcn = createCallbackFcn(app, @cond_sliderValueChanged, true);
            app.cond_slider.MinorTicks = [];
            app.cond_slider.Value = 0.16;
            app.cond_box = uieditfield(app.parametersPanel, 'numeric');
            app.cond_box.ValueChangedFcn = createCallbackFcn(app, @cond_boxValueChanged, true);
            app.cond_box.Value = 0.16;
            app.StimulationNameEditFieldLabel = uilabel(app.parametersPanel);
            app.StimulationNameEditFieldLabel.Text = 'Stimulation Name';
            app.stim_name = uieditfield(app.parametersPanel, 'text');
            app.stim_name.Value = 'Stim1';
            app.ButtonGroup = uibuttongroup(app.parametersPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.right_side = uiradiobutton(app.ButtonGroup);
            app.right_side.Text = 'Right';
            app.right_side.Value = true;
            app.left_side = uiradiobutton(app.ButtonGroup);
            app.left_side.Text = 'Left';
            app.SideDropDownLabel_2 = uilabel(app.parametersPanel);
            app.SideDropDownLabel_2.Text = 'Side';
            app.ElectrodeTypeLabel = uilabel(app.parametersPanel);
            app.ElectrodeTypeLabel.Text = 'Electrode Type';
            app.electrode_type = uidropdown(app.parametersPanel);
            app.electrode_type.Items = {'boston_vercise_directed', 'boston_vercise', 'medtronic_3389', 'medtronic_3387', 'medtronic_3391', 'stjude_directed_short', 'stjude_directed_long', 'stjude_activetip_2mm', 'stjude_activetip_3mm', ''};
            app.electrode_type.ValueChangedFcn = createCallbackFcn(app, @electrode_typeValueChanged, true);
            app.electrode_type.Value = '';
            app.atlas_button = uibutton(app.parametersPanel, 'push');
            app.atlas_button.ButtonPushedFcn = createCallbackFcn(app, @atlas_buttonButtonPushed, true);
            app.atlas_button.Text = 'Atlas';
            app.SideDropDownLabel_3 = uilabel(app.parametersPanel);
            app.SideDropDownLabel_3.Text = 'VTA Tresh. (V/mm)';
            app.btnApproximateFromPW = uibutton(app.parametersPanel, 'push');
            app.btnApproximateFromPW.ButtonPushedFcn = createCallbackFcn(app, @onBtnApproximateFromPWClicked, true);
            app.btnApproximateFromPW.Text = 'Approximate from PW';
            app.inputEFieldThreshold = uieditfield(app.parametersPanel, 'numeric');
            app.inputEFieldThreshold.ValueChangedFcn = createCallbackFcn(app, @onInputEFieldThresholdClicked, true);
            app.inputEFieldThreshold.Value = 0.2;
            app.patient_path = uibutton(app.UIFigure, 'push');
            app.patient_path.ButtonPushedFcn = createCallbackFcn(app, @patient_pathButtonPushed, true);
            app.patient_path.Text = 'Choose Patient Directory';
            app.fastfield_path = uibutton(app.UIFigure, 'push');
            app.fastfield_path.ButtonPushedFcn = createCallbackFcn(app, @fastfield_pathButtonPushed, true);
            app.fastfield_path.Text = 'Choose FastField Directory';
            app.axes_el = uiaxes(app.UIFigure);
            title(app.axes_el, '')
            app.axes_el.XTick = [];
            app.axes_el.YTick = [];
            app.ContactSPanel = uipanel(app.UIFigure);
            app.ContactSPanel.Title = 'ContactS';
            app.con8_per = uilabel(app.ContactSPanel);
            app.con8_per.Text = '%';
            app.Contact8Label = uilabel(app.ContactSPanel);
            app.Contact8Label.Text = 'Contact 8';
            app.con8 = uieditfield(app.ContactSPanel, 'numeric');
            app.con8.ValueChangedFcn = createCallbackFcn(app, @con8ValueChanged, true);
            app.Contact7Label = uilabel(app.ContactSPanel);
            app.Contact7Label.Text = 'Contact 7';
            app.con7 = uieditfield(app.ContactSPanel, 'numeric');
            app.con7.ValueChangedFcn = createCallbackFcn(app, @con7ValueChanged, true);
            app.Contact6Label = uilabel(app.ContactSPanel);
            app.Contact6Label.Text = 'Contact 6';
            app.con6 = uieditfield(app.ContactSPanel, 'numeric');
            app.con6.ValueChangedFcn = createCallbackFcn(app, @con6ValueChanged, true);
            app.Contact5Label = uilabel(app.ContactSPanel);
            app.Contact5Label.Text = 'Contact 5';
            app.con5 = uieditfield(app.ContactSPanel, 'numeric');
            app.con5.ValueChangedFcn = createCallbackFcn(app, @con5ValueChanged, true);
            app.Contact4Label = uilabel(app.ContactSPanel);
            app.Contact4Label.Text = 'Contact 4';
            app.con4 = uieditfield(app.ContactSPanel, 'numeric');
            app.con4.ValueChangedFcn = createCallbackFcn(app, @con4ValueChanged, true);
            app.Contact3Label = uilabel(app.ContactSPanel);
            app.Contact3Label.Text = 'Contact 3';
            app.con3 = uieditfield(app.ContactSPanel, 'numeric');
            app.con3.ValueChangedFcn = createCallbackFcn(app, @con3ValueChanged, true);
            app.Contact2Label = uilabel(app.ContactSPanel);
            app.Contact2Label.Text = 'Contact 2';
            app.con2 = uieditfield(app.ContactSPanel, 'numeric');
            app.con2.ValueChangedFcn = createCallbackFcn(app, @con2ValueChanged, true);
            app.Contact1Label = uilabel(app.ContactSPanel);
            app.Contact1Label.Text = 'Contact 1';
            app.con1 = uieditfield(app.ContactSPanel, 'numeric');
            app.con1.ValueChangedFcn = createCallbackFcn(app, @con1ValueChanged, true);
            app.con7_per = uilabel(app.ContactSPanel);
            app.con7_per.Text = '%';
            app.con6_per = uilabel(app.ContactSPanel);
            app.con6_per.Text = '%';
            app.con5_per = uilabel(app.ContactSPanel);
            app.con5_per.Text = '%';
            app.con4_per = uilabel(app.ContactSPanel);
            app.con4_per.Text = '%';
            app.con3_per = uilabel(app.ContactSPanel);
            app.con3_per.Text = '%';
            app.con2_per = uilabel(app.ContactSPanel);
            app.con2_per.Text = '%';
            app.con1_per = uilabel(app.ContactSPanel);
            app.con1_per.Text = '%';
            app.axes_result = uiaxes(app.UIFigure);
            app.axes_result.XTick = [];
            app.axes_result.YTick = [];
            app.axes_result.YTickLabel = '';
            app.axes_result.ZTick = [];
            app.stim_button = uibutton(app.UIFigure, 'push');
            app.stim_button.ButtonPushedFcn = createCallbackFcn(app, @stim_buttonButtonPushed, true);
            app.stim_button.Text = 'Display';
            app.ErrorLabel = uilabel(app.UIFigure);
            app.ErrorLabel.Text = 'Error';
            app.error_box = uieditfield(app.UIFigure, 'text');
            app.error_box.ValueChangedFcn = createCallbackFcn(app, @error_boxValueChanged2, true);
            app.LightSwitchLabel = uilabel(app.UIFigure);
            app.LightSwitchLabel.Text = 'Light';
            app.LightSwitch = uiswitch(app.UIFigure, 'slider');
            app.LightSwitch.ValueChangedFcn = createCallbackFcn(app, @LightSwitchValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = FastField(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end